classdef gui_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EtchASketchUploaderUIFigure  matlab.ui.Figure
        ResolutionSlider             matlab.ui.control.Slider
        PreviewButton                matlab.ui.control.Button
        OrientationDropDown          matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        ContrastSlider               matlab.ui.control.Slider
        PrintButton                  matlab.ui.control.Button
        UploadImageButton            matlab.ui.control.Button
        EtchASketchUploaderLabel     matlab.ui.control.Label
        CheckBox                     matlab.ui.control.CheckBox
        Resnumber                    matlab.ui.control.NumericEditField
        Tolnumber                    matlab.ui.control.NumericEditField
        Team12RyanMaziarzJamesNellisandRuijiLiuLabel  matlab.ui.control.Label
        YearLabel                    matlab.ui.control.Label
        EtchASketchPortEditField     matlab.ui.control.NumericEditField
        Button_help                  matlab.ui.control.Button
        ParameterControlButtonGroup  matlab.ui.container.ButtonGroup
        ResolutionButton             matlab.ui.control.RadioButton
        ContrastButton               matlab.ui.control.RadioButton
        PortNumberButton             matlab.ui.control.RadioButton
        OrientationButton            matlab.ui.control.RadioButton
        ImagingButton                matlab.ui.control.RadioButton
        ShadingButton                matlab.ui.control.RadioButton
        ResolutionLabel              matlab.ui.control.Label
        ContrastLabel                matlab.ui.control.Label
        OrientationLabel             matlab.ui.control.Label
        EtchASketchPortNumberLabel   matlab.ui.control.Label
        OSDropDownLabel              matlab.ui.control.Label
        OSDropDown                   matlab.ui.control.DropDown
        MacPortString                matlab.ui.control.EditField
        NotReadytoPrintLabel         matlab.ui.control.Label
        InvalidPortLabel             matlab.ui.control.Label
        SmartRenderButtonGroup       matlab.ui.container.ButtonGroup
        OnButton                     matlab.ui.control.RadioButton
        OffButton                    matlab.ui.control.RadioButton
        StretchButtonGroup           matlab.ui.container.ButtonGroup
        VerticalButton               matlab.ui.control.ToggleButton
        HorizontalButton             matlab.ui.control.ToggleButton
        FitButton                    matlab.ui.control.ToggleButton
        NoStretchButton              matlab.ui.control.ToggleButton
        NumberofShadesLabel          matlab.ui.control.Label
        NumberofShadesSlider         matlab.ui.control.Slider
        Label                        matlab.ui.control.Label
        ShadeNO                      matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        image
        Comppath
        parameter
        curdir
        curstretch
        stretchvalue
        Port
        render=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.curdir = app.OrientationDropDown.Value;
            app.parameter = app.ContrastSlider.Value * app.ResolutionSlider.Value;
            app.curstretch = 'None';
            app.stretchvalue = app.curstretch;
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            close all
            figure(app.EtchASketchUploaderUIFigure)
            
            %Variables from user input
            resolution = app.ResolutionSlider.Value;
            orientation = app.OrientationDropDown.Value;
            tolerance = app.ContrastSlider.Value;
            
            %User decision to use geodesics or not
            if app.SmartRenderButtonGroup.SelectedObject==app.OnButton
                app.render=1;
            else
                app.render=0;
            end
            
            %User decision to stretch path image
            if app.VerticalButton.Value==1
                stretch='Vert';
            elseif app.HorizontalButton.Value==1
                stretch='Hor';
            elseif app.FitButton.Value==1
                stretch='Both';
            else
                stretch='None';
            end
            app.curstretch=stretch;
            
            %Variables to check if parameter or orientation have changed
            app.parameter = app.ContrastSlider.Value * app.ResolutionSlider.Value;
            app.curdir = app.OrientationDropDown.Value;
            
            %Conditional Statements to allow the user to print or not
            if app.CheckBox.Value == 1
                preview = ImageTranslator(app.image,resolution,orientation,tolerance,app.render,stretch,app.ShadeNO.Value);
                app.Comppath = preview;
                if app.CheckBox.Value==1 && sum(strcmp(app.Port,serialportlist),'all')>0
                    app.NotReadytoPrintLabel.BackgroundColor = [0 1 0];
                    app.NotReadytoPrintLabel.Text = {'Ready to Print'; ''};
                    app.NotReadytoPrintLabel.Tooltip = {'Click "Print" when ready'};
                    app.PrintButton.Enable = 'on';
                else
                    app.NotReadytoPrintLabel.BackgroundColor = [1 0 0];
                    app.NotReadytoPrintLabel.Text = {'Not Ready to Print'; ''};
                    if app.CheckBox.Value==0
                        app.NotReadytoPrintLabel.Tooltip = {'Is an image uploaded?'};
                    elseif sum(strcmp(app.Port,serialportlist),'all')==0
                        app.NotReadytoPrintLabel.Tooltip = {'Check the Port Number'};
                    else
                        app.NotReadytoPrintLabel.Tooltip = {'Hit "Preview" to update parameters'};
                    end
                    app.PrintButton.Enable = 'off';
                end
            end
            
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            app.image = [];
            
            %Create dummy figure to prevent gui from minimizing
            dummy=figure('Visible',"off");            
            drawnow;
            
            %Get file requested
            [file,path] = uigetfile({'*.png;*.jpg','Select A File'});
            figure(app.EtchASketchUploaderUIFigure)
            
            %Delete dummy figure
            delete(dummy);
            
            %Checking for valid image
            if path~=0
                cd(path);
                app.image = imread([path file]);
                app.CheckBox.Value = 1;
                app.CheckBox.Tooltip = {'Image successfully uploaded'; ''};
                imshow(app.image,'parent',app.UIAxes);
                
                %Image calculations for a recommended contrast number
                img=rgb2gray(app.image);
                img=double(img);
                [Gmag,Gdir]=imgradient(img);
                
                highgrad=size(find(Gmag>30));
                lowgrad=size(find(Gmag<30));
                
                recommend=(lowgrad/highgrad).^(0.1);
                if recommend>2
                    recommend=1.25;
                end
                app.ContrastSlider.Value=recommend;
                app.Tolnumber.Value=recommend;
            end
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
            app.Tolnumber.Value = value;
            
            %Conditional Statements to allow the user to print or not
            if app.CheckBox.Value==1 && app.parameter==app.ContrastSlider.Value*app.ResolutionSlider.Value && strcmp(app.curdir,app.OrientationDropDown.Value)==1 && strcmp(app.curstretch,app.stretchvalue)==1 && sum(strcmp(app.Port,serialportlist),'all')>0
                app.NotReadytoPrintLabel.BackgroundColor = [0 1 0];
                app.NotReadytoPrintLabel.Text = {'Ready to Print'; ''};
                app.NotReadytoPrintLabel.Tooltip = {'Click "Print" when ready'};
                app.PrintButton.Enable = 'on';
            else
                app.NotReadytoPrintLabel.BackgroundColor = [1 0 0];
                app.NotReadytoPrintLabel.Text = {'Not Ready to Print'; ''};
                if app.CheckBox.Value==0
                        app.NotReadytoPrintLabel.Tooltip = {'Is an image uploaded?'};
                    elseif sum(strcmp(app.Port,serialportlist),'all')==0
                        app.NotReadytoPrintLabel.Tooltip = {'Check the Port Number'};
                    else
                        app.NotReadytoPrintLabel.Tooltip = {'Hit "Preview" to update parameters'};
                end                
                app.PrintButton.Enable = 'off';
            end
        end

        % Value changed function: ResolutionSlider
        function ResolutionSliderValueChanged(app, event)
            value = app.ResolutionSlider.Value;
            app.Resnumber.Value = value;
            
            %Conditional Statements to allow the user to print or not
            if app.CheckBox.Value==1 && app.parameter==app.ContrastSlider.Value*app.ResolutionSlider.Value && strcmp(app.curdir,app.OrientationDropDown.Value)==1 && strcmp(app.curstretch,app.stretchvalue)==1 && sum(strcmp(app.Port,serialportlist),'all')>0
                app.NotReadytoPrintLabel.BackgroundColor = [0 1 0];
                app.NotReadytoPrintLabel.Text = {'Ready to Print'; ''};
                app.NotReadytoPrintLabel.Tooltip = {'Click "Print" when ready'};
                app.PrintButton.Enable = 'on';
            else
                app.NotReadytoPrintLabel.BackgroundColor = [1 0 0];
                app.NotReadytoPrintLabel.Text = {'Not Ready to Print'; ''};
                if app.CheckBox.Value==0
                        app.NotReadytoPrintLabel.Tooltip = {'Is an image uploaded?'};
                    elseif sum(strcmp(app.Port,serialportlist),'all')==0
                        app.NotReadytoPrintLabel.Tooltip = {'Check the Port Number'};
                    else
                        app.NotReadytoPrintLabel.Tooltip = {'Hit "Preview" to update parameters'};
                end
                app.PrintButton.Enable = 'off';
            end
        end

        % Value changed function: Resnumber
        function ResnumberValueChanged(app, event)
            value = app.Resnumber.Value;
            app.ResolutionSlider.Value = value;
        end

        % Value changed function: Tolnumber
        function TolnumberValueChanged(app, event)
            value = app.Tolnumber.Value;
            app.ContrastSlider.Value = value;
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            basecode(app.Comppath,app.Port)
        end

        % Button pushed function: Button_help
        function HelpButton_help(app, event)
            open helpguide.pdf
        end

        % Value changed function: EtchASketchPortEditField
        function EtchASketchPortEditFieldValueChanged(app, event)
            %Allows user to specify OS version and adjusts inputs
            %accordingly
            if strcmp(app.OSDropDown.Value,'Windows')==1
                app.Port = sprintf('COM%d',app.EtchASketchPortEditField.Value);
            else
                app.Port = app.MacPortString.Value;
            end
            
            %Check if port is valid
            if sum(strcmp(app.Port,serialportlist),'all')>0
                app.InvalidPortLabel.Text = {'Port Found'; ''};
                app.InvalidPortLabel.BackgroundColor = [0 1 0];
            else
                app.InvalidPortLabel.Text = {'Invalid Port'; ''};
                app.InvalidPortLabel.BackgroundColor = [1 0 0];
            end
            
            %Conditional Statements to allow the user to print or not
            if app.CheckBox.Value==1 && app.parameter==app.ContrastSlider.Value*app.ResolutionSlider.Value && strcmp(app.curdir,app.OrientationDropDown.Value)==1 && strcmp(app.curstretch,app.stretchvalue)==1 && sum(strcmp(app.Port,serialportlist),'all')>0
                app.NotReadytoPrintLabel.BackgroundColor = [0 1 0];
                app.NotReadytoPrintLabel.Text = {'Ready to Print'; ''};
                app.NotReadytoPrintLabel.Tooltip = {'Click "Print" when ready'};
                app.PrintButton.Enable = 'on';
            else
                app.NotReadytoPrintLabel.BackgroundColor = [1 0 0];
                app.NotReadytoPrintLabel.Text = {'Not Ready to Print'; ''};
                if app.CheckBox.Value==0
                        app.NotReadytoPrintLabel.Tooltip = {'Is an image uploaded?'};
                    elseif sum(strcmp(app.Port,serialportlist),'all')==0
                        app.NotReadytoPrintLabel.Tooltip = {'Check the Port Number'};
                    else
                        app.NotReadytoPrintLabel.Tooltip = {'Hit "Preview" to update parameters'};
                end
                app.PrintButton.Enable = 'off';
            end
        end

        % Selection changed function: ParameterControlButtonGroup
        function ParameterControlButtonGroupSelectionChanged(app, event)
            Button = app.ParameterControlButtonGroup.SelectedObject;
            %View control button
            if Button == app.ResolutionButton
                app.ResolutionSlider.Visible = 'on';
                app.Resnumber.Visible = 'on';
                app.ResolutionLabel.Visible = 'on';
                app.ContrastSlider.Visible = 'off';
                app.Tolnumber.Visible = 'off';
                app.ContrastLabel.Visible = 'off';
                app.OrientationDropDown.Visible = 'off';
                app.OrientationLabel.Visible = 'off';
                app.EtchASketchPortEditField.Visible = 'off';
                app.EtchASketchPortNumberLabel.Visible = 'off';
                app.OSDropDown.Visible = 'off';
                app.OSDropDownLabel.Visible = 'off';
                app.EtchASketchPortEditField.Visible = 'off';
                app.MacPortString.Visible = 'off';
                app.Button_help.Visible = 'off';
                app.InvalidPortLabel.Visible = 'off';
                app.SmartRenderButtonGroup.Visible = 'off';
                app.StretchButtonGroup.Visible = 'off';
                app.NumberofShadesLabel.Visible = 'off';
                app.NumberofShadesSlider.Visible = 'off';
                app.ShadeNO.Visible = 'off';
            elseif Button == app.ContrastButton
                app.ResolutionSlider.Visible = 'off';
                app.Resnumber.Visible = 'off';
                app.ResolutionLabel.Visible = 'off';
                app.ContrastSlider.Visible = 'on';
                app.Tolnumber.Visible = 'on';
                app.ContrastLabel.Visible = 'on';
                app.OrientationDropDown.Visible = 'off';
                app.OrientationLabel.Visible = 'off';
                app.EtchASketchPortEditField.Visible = 'off';
                app.EtchASketchPortNumberLabel.Visible = 'off';
                app.OSDropDown.Visible = 'off';
                app.OSDropDownLabel.Visible = 'off';
                app.EtchASketchPortEditField.Visible = 'off';
                app.MacPortString.Visible = 'off';
                app.Button_help.Visible = 'off';
                app.Button_help.Visible = 'off';
                app.InvalidPortLabel.Visible = 'off';
                app.SmartRenderButtonGroup.Visible = 'off';
                app.StretchButtonGroup.Visible = 'off';
                app.NumberofShadesLabel.Visible = 'off';
                app.NumberofShadesSlider.Visible = 'off';
                app.ShadeNO.Visible = 'off';
            elseif Button == app.OrientationButton
                app.ResolutionSlider.Visible = 'off';
                app.Resnumber.Visible = 'off';
                app.ResolutionLabel.Visible = 'off';
                app.ContrastSlider.Visible = 'off';
                app.Tolnumber.Visible = 'off';
                app.ContrastLabel.Visible = 'off';
                app.OrientationDropDown.Visible = 'on';
                app.OrientationLabel.Visible = 'on';
                app.EtchASketchPortEditField.Visible = 'off';
                app.EtchASketchPortNumberLabel.Visible = 'off';
                app.OSDropDown.Visible = 'off';
                app.OSDropDownLabel.Visible = 'off';
                app.EtchASketchPortEditField.Visible = 'off';
                app.MacPortString.Visible = 'off';
                app.Button_help.Visible = 'off';
                app.InvalidPortLabel.Visible = 'off';
                app.SmartRenderButtonGroup.Visible = 'off';
                app.StretchButtonGroup.Visible = 'off';
                app.NumberofShadesLabel.Visible = 'off';
                app.NumberofShadesSlider.Visible = 'off';
                app.ShadeNO.Visible = 'off';
            elseif Button == app.PortNumberButton
                app.ResolutionSlider.Visible = 'off';
                app.Resnumber.Visible = 'off';
                app.ResolutionLabel.Visible = 'off';
                app.ContrastSlider.Visible = 'off';
                app.Tolnumber.Visible = 'off';
                app.ContrastLabel.Visible = 'off';
                app.OrientationDropDown.Visible = 'off';
                app.OrientationLabel.Visible = 'off';
                app.EtchASketchPortEditField.Visible = 'on';
                app.EtchASketchPortNumberLabel.Visible = 'on';
                app.OSDropDown.Visible = 'on';
                app.OSDropDownLabel.Visible = 'on';
                app.InvalidPortLabel.Visible = 'on';
                if strcmp(app.OSDropDown.Value,'Windows')==1
                    app.EtchASketchPortEditField.Visible = 'on';
                    app.MacPortString.Visible = 'off';
                else
                    app.EtchASketchPortEditField.Visible = 'off';
                    app.MacPortString.Visible = 'on';
                end
                app.Button_help.Visible = 'on';
                app.SmartRenderButtonGroup.Visible = 'off';
                app.StretchButtonGroup.Visible = 'off';
                app.NumberofShadesLabel.Visible = 'off';
                app.NumberofShadesSlider.Visible = 'off';
                app.ShadeNO.Visible = 'off';
            elseif Button == app.ImagingButton
                app.ResolutionSlider.Visible = 'off';
                app.Resnumber.Visible = 'off';
                app.ResolutionLabel.Visible = 'off';
                app.ContrastSlider.Visible = 'off';
                app.Tolnumber.Visible = 'off';
                app.ContrastLabel.Visible = 'off';
                app.OrientationDropDown.Visible = 'off';
                app.OrientationLabel.Visible = 'off';
                app.EtchASketchPortEditField.Visible = 'off';
                app.EtchASketchPortNumberLabel.Visible = 'off';
                app.OSDropDown.Visible = 'off';
                app.OSDropDownLabel.Visible = 'off';
                app.EtchASketchPortEditField.Visible = 'off';
                app.MacPortString.Visible = 'off';
                app.Button_help.Visible = 'off';
                app.InvalidPortLabel.Visible = 'off';
                app.SmartRenderButtonGroup.Visible = 'on';
                app.StretchButtonGroup.Visible = 'on';
                app.NumberofShadesLabel.Visible = 'off';
                app.NumberofShadesSlider.Visible = 'off';
                app.ShadeNO.Visible = 'off';
            elseif Button == app.ShadingButton
                app.ResolutionSlider.Visible = 'off';
                app.Resnumber.Visible = 'off';
                app.ResolutionLabel.Visible = 'off';
                app.ContrastSlider.Visible = 'off';
                app.Tolnumber.Visible = 'off';
                app.ContrastLabel.Visible = 'off';
                app.OrientationDropDown.Visible = 'off';
                app.OrientationLabel.Visible = 'off';
                app.EtchASketchPortEditField.Visible = 'off';
                app.EtchASketchPortNumberLabel.Visible = 'off';
                app.OSDropDown.Visible = 'off';
                app.OSDropDownLabel.Visible = 'off';
                app.EtchASketchPortEditField.Visible = 'off';
                app.MacPortString.Visible = 'off';
                app.Button_help.Visible = 'off';
                app.InvalidPortLabel.Visible = 'off';
                app.SmartRenderButtonGroup.Visible = 'off';
                app.StretchButtonGroup.Visible = 'off';
                app.NumberofShadesLabel.Visible = 'on';
                app.NumberofShadesSlider.Visible = 'on';
                app.ShadeNO.Visible = 'on';
            end
        end

        % Value changed function: OSDropDown
        function OSDropDownValueChanged(app, event)
            value = app.OSDropDown.Value;
            
            %OS version control to update with dropdown menu
            if strcmp(value,'Windows')==1
                app.EtchASketchPortEditField.Visible = 'on';
                app.MacPortString.Visible = 'off';
            else
                app.EtchASketchPortEditField.Visible = 'off';
                app.MacPortString.Visible = 'on';
            end
        end

        % Value changed function: OrientationDropDown
        function OrientationDropDownValueChanged(app, event)
            %Conditional Statements to allow the user to print or not
            if app.CheckBox.Value==1 && app.parameter==app.ContrastSlider.Value*app.ResolutionSlider.Value && strcmp(app.curdir,app.OrientationDropDown.Value)==1 && strcmp(app.curstretch,app.stretchvalue)==1 && sum(strcmp(app.Port,serialportlist),'all')>0
                app.NotReadytoPrintLabel.BackgroundColor = [0 1 0];
                app.NotReadytoPrintLabel.Text = {'Ready to Print'; ''};
                app.NotReadytoPrintLabel.Tooltip = {'Click "Print" when ready'};
                app.PrintButton.Enable = 'on';
            else
                app.NotReadytoPrintLabel.BackgroundColor = [1 0 0];
                app.NotReadytoPrintLabel.Text = {'Not Ready to Print'; ''};
                if app.CheckBox.Value==0
                        app.NotReadytoPrintLabel.Tooltip = {'Is an image uploaded?'};
                    elseif sum(strcmp(app.Port,serialportlist),'all')==0
                        app.NotReadytoPrintLabel.Tooltip = {'Check the Port Number'};
                    else
                        app.NotReadytoPrintLabel.Tooltip = {'Hit "Preview" to update parameters'};
                end
                app.PrintButton.Enable = 'off';
            end
        end

        % Selection changed function: SmartRenderButtonGroup
        function SmartRenderButtonGroupSelectionChanged(app, event)
            %Disables print to account for new user parameters
            app.NotReadytoPrintLabel.Tooltip = {'Hit "Preview" to update parameters'};
            app.PrintButton.Enable = 'off';
        end

        % Selection changed function: StretchButtonGroup
        function StretchButtonGroupSelectionChanged(app, event)
            %Disables print to account for new user parameters
            app.NotReadytoPrintLabel.Tooltip = {'Hit "Preview" to update parameters'};
            app.PrintButton.Enable = 'off';
        end

        % Value changed function: NumberofShadesSlider
        function NumberofShadesSliderValueChanged(app, event)
            value = app.NumberofShadesSlider.Value;
            
            %Quantize shade values to whole numbers
            value = round(value);
            app.NumberofShadesSlider.Value = value;
            app.ShadeNO.Value=value;
            
            %Parameters changed, do not allow user to print
            app.NotReadytoPrintLabel.Tooltip = {'Hit "Preview" to update parameters'};
            app.PrintButton.Enable = 'off';
        end

        % Value changed function: ShadeNO
        function ShadeNOValueChanged(app, event)
            value = app.ShadeNO.Value;
            
            %Quantize shade values to whole numbers
            value = round(value);
            app.NumberofShadesSlider.Value=value;
            
            %Parameters changed, do not allow user to print
            app.NotReadytoPrintLabel.Tooltip = {'Hit "Preview" to update parameters'};
            app.PrintButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EtchASketchUploaderUIFigure = uifigure('Visible', 'off');
            app.EtchASketchUploaderUIFigure.Name = 'Etch-A-Sketch Uploader';
            app.ResolutionSlider = uislider(app.EtchASketchUploaderUIFigure);
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionSliderValueChanged, true);
            app.ResolutionSlider.Visible = 'off';
            app.ResolutionSlider.Tooltip = {'Control the number of pixels used'};
            app.ResolutionSlider.Value = 100;
            app.PreviewButton = uibutton(app.EtchASketchUploaderUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Tooltip = {'Preview the image prior to printing'};
            app.PreviewButton.Text = {'Preview'; ''};
            app.OrientationDropDown = uidropdown(app.EtchASketchUploaderUIFigure);
            app.OrientationDropDown.Items = {'Down', 'Up', 'Left', 'Right'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationDropDownValueChanged, true);
            app.OrientationDropDown.Visible = 'off';
            app.OrientationDropDown.Tooltip = {'Control which direction the bottom of the image faces'};
            app.OrientationDropDown.Value = 'Down';
            app.UIAxes = uiaxes(app.EtchASketchUploaderUIFigure);
            title(app.UIAxes, 'User Image    ')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.ContrastSlider = uislider(app.EtchASketchUploaderUIFigure);
            app.ContrastSlider.Limits = [0 2];
            app.ContrastSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Visible = 'off';
            app.ContrastSlider.Tooltip = {'Control the contrast of the image.'};
            app.ContrastSlider.Value = 1;
            app.PrintButton = uibutton(app.EtchASketchUploaderUIFigure, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Enable = 'off';
            app.PrintButton.Tooltip = {'Print the image on the Etch-A-Sketch'};
            app.PrintButton.Text = {'Print'; ''};
            app.UploadImageButton = uibutton(app.EtchASketchUploaderUIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Tooltip = {'Upload an image to print'};
            app.UploadImageButton.Text = 'Upload Image';
            app.EtchASketchUploaderLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.EtchASketchUploaderLabel.Text = 'Etch-A-Sketch Uploader ';
            app.CheckBox = uicheckbox(app.EtchASketchUploaderUIFigure);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Tooltip = {'No image uploaded'};
            app.CheckBox.Text = '';
            app.Resnumber = uieditfield(app.EtchASketchUploaderUIFigure, 'numeric');
            app.Resnumber.Limits = [0 100];
            app.Resnumber.ValueDisplayFormat = '%.1f';
            app.Resnumber.ValueChangedFcn = createCallbackFcn(app, @ResnumberValueChanged, true);
            app.Resnumber.Visible = 'off';
            app.Resnumber.Value = 100;
            app.Tolnumber = uieditfield(app.EtchASketchUploaderUIFigure, 'numeric');
            app.Tolnumber.Limits = [0 2];
            app.Tolnumber.ValueDisplayFormat = '%.2f';
            app.Tolnumber.ValueChangedFcn = createCallbackFcn(app, @TolnumberValueChanged, true);
            app.Tolnumber.Visible = 'off';
            app.Tolnumber.Value = 1;
            app.Team12RyanMaziarzJamesNellisandRuijiLiuLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.Team12RyanMaziarzJamesNellisandRuijiLiuLabel.Text = {'Team 1.2       Ryan Maziarz, James Nellis, and Ruiji Liu'; ''};
            app.YearLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.YearLabel.Text = '2021';
            app.EtchASketchPortEditField = uieditfield(app.EtchASketchUploaderUIFigure, 'numeric');
            app.EtchASketchPortEditField.ValueChangedFcn = createCallbackFcn(app, @EtchASketchPortEditFieldValueChanged, true);
            app.EtchASketchPortEditField.Tooltip = {''};
            app.Button_help = uibutton(app.EtchASketchUploaderUIFigure, 'push');
            app.Button_help.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_help, true);
            app.Button_help.Tooltip = {'Help'};
            app.Button_help.Text = '?';
            app.ParameterControlButtonGroup = uibuttongroup(app.EtchASketchUploaderUIFigure);
            app.ParameterControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParameterControlButtonGroupSelectionChanged, true);
            app.ParameterControlButtonGroup.Tooltip = {''};
            app.ParameterControlButtonGroup.Title = 'Parameter Control';
            app.ResolutionButton = uiradiobutton(app.ParameterControlButtonGroup);
            app.ResolutionButton.Text = 'Resolution';
            app.ContrastButton = uiradiobutton(app.ParameterControlButtonGroup);
            app.ContrastButton.Text = 'Contrast';
            app.PortNumberButton = uiradiobutton(app.ParameterControlButtonGroup);
            app.PortNumberButton.Text = 'Port Number';
            app.PortNumberButton.Value = true;
            app.OrientationButton = uiradiobutton(app.ParameterControlButtonGroup);
            app.OrientationButton.Text = 'Orientation';
            app.ImagingButton = uiradiobutton(app.ParameterControlButtonGroup);
            app.ImagingButton.Text = {'Imaging'; ''};
            app.ShadingButton = uiradiobutton(app.ParameterControlButtonGroup);
            app.ShadingButton.Text = {'Shading'; ''};
            app.ResolutionLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.ResolutionLabel.Visible = 'off';
            app.ResolutionLabel.Tooltip = {'Control the number of pixels used'};
            app.ResolutionLabel.Text = {'Resolution'; ''};
            app.ContrastLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.ContrastLabel.Visible = 'off';
            app.ContrastLabel.Tooltip = {'Control the contrast of the image.'};
            app.ContrastLabel.Text = 'Contrast';
            app.OrientationLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.OrientationLabel.Visible = 'off';
            app.OrientationLabel.Tooltip = {'Control which direction the bottom of the image faces'};
            app.OrientationLabel.Text = 'Orientation';
            app.EtchASketchPortNumberLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.EtchASketchPortNumberLabel.Tooltip = {'Enter the COM Port Number'};
            app.EtchASketchPortNumberLabel.Text = {'Etch-A-Sketch Port Number'; ''};
            app.OSDropDownLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.OSDropDownLabel.Text = 'OS';
            app.OSDropDown = uidropdown(app.EtchASketchUploaderUIFigure);
            app.OSDropDown.Items = {'Windows', 'Mac'};
            app.OSDropDown.ValueChangedFcn = createCallbackFcn(app, @OSDropDownValueChanged, true);
            app.OSDropDown.Value = 'Windows';
            app.MacPortString = uieditfield(app.EtchASketchUploaderUIFigure, 'text');
            app.MacPortString.Visible = 'off';
            app.MacPortString.Value = 'Port Address';
            app.NotReadytoPrintLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.NotReadytoPrintLabel.Tooltip = {'Is an image uploaded?'};
            app.NotReadytoPrintLabel.Text = 'Not Ready to Print';
            app.InvalidPortLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.InvalidPortLabel.Tooltip = {''};
            app.InvalidPortLabel.Text = 'Invalid Port';
            app.SmartRenderButtonGroup = uibuttongroup(app.EtchASketchUploaderUIFigure);
            app.SmartRenderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SmartRenderButtonGroupSelectionChanged, true);
            app.SmartRenderButtonGroup.Tooltip = {'May increase path creation time significantly'; 'Cleans up the image'; 'Works better at lower resolutions'};
            app.SmartRenderButtonGroup.Title = 'Smart Render';
            app.SmartRenderButtonGroup.Visible = 'off';
            app.OnButton = uiradiobutton(app.SmartRenderButtonGroup);
            app.OnButton.Text = 'On';
            app.OffButton = uiradiobutton(app.SmartRenderButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.StretchButtonGroup = uibuttongroup(app.EtchASketchUploaderUIFigure);
            app.StretchButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StretchButtonGroupSelectionChanged, true);
            app.StretchButtonGroup.Title = 'Stretch';
            app.StretchButtonGroup.Visible = 'off';
            app.VerticalButton = uitogglebutton(app.StretchButtonGroup);
            app.VerticalButton.Tooltip = {'Stretch the Vertical image bounds to the Etch-A-Sketch'};
            app.VerticalButton.Text = {'Vertical'; ''};
            app.HorizontalButton = uitogglebutton(app.StretchButtonGroup);
            app.HorizontalButton.Tooltip = {'Stretch the Horizontal image bounds to the Etch-A-Sketch'};
            app.HorizontalButton.Text = {'Horizontal'; ''};
            app.FitButton = uitogglebutton(app.StretchButtonGroup);
            app.FitButton.Tooltip = {'Stretch image to fit both Horizontal and Vertical Etch-A-Sketch bounds'};
            app.FitButton.Text = {'Fit'; ''};
            app.NoStretchButton = uitogglebutton(app.StretchButtonGroup);
            app.NoStretchButton.Tooltip = {'Do not stretch'};
            app.NoStretchButton.Text = 'No Stretch';
            app.NoStretchButton.Value = true;
            app.NumberofShadesLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.NumberofShadesLabel.Visible = 'off';
            app.NumberofShadesLabel.Text = {'Number of'; ' Shades'};
            app.NumberofShadesSlider = uislider(app.EtchASketchUploaderUIFigure);
            app.NumberofShadesSlider.Limits = [1 10];
            app.NumberofShadesSlider.MajorTicks = [0 2 4 6 8 10];
            app.NumberofShadesSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofShadesSliderValueChanged, true);
            app.NumberofShadesSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofShadesSlider.Visible = 'off';
            app.NumberofShadesSlider.Tooltip = {'Control the number of colored shades in the recreated image'};
            app.NumberofShadesSlider.Value = 3;
            app.Label = uilabel(app.EtchASketchUploaderUIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.ShadeNO = uieditfield(app.EtchASketchUploaderUIFigure, 'numeric');
            app.ShadeNO.Limits = [1 10];
            app.ShadeNO.ValueChangedFcn = createCallbackFcn(app, @ShadeNOValueChanged, true);
            app.ShadeNO.Visible = 'off';
            app.ShadeNO.Value = 3;
            % Show the figure after all components are created
            app.EtchASketchUploaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EtchASketchUploaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EtchASketchUploaderUIFigure)
        end
    end
end